classdef Calculadora_de_Transformadores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Button                      matlab.ui.control.Button
        voltaje                     matlab.ui.control.NumericEditField
        amperaje                    matlab.ui.control.NumericEditField
        m1x                         matlab.ui.control.NumericEditField
        m4x                         matlab.ui.control.NumericEditField
        m3x                         matlab.ui.control.NumericEditField
        mp                          matlab.ui.control.NumericEditField
        m4z                         matlab.ui.control.NumericEditField
        m3z                         matlab.ui.control.NumericEditField
        m2z                         matlab.ui.control.NumericEditField
        mu                          matlab.ui.control.NumericEditField
        vueltas                     matlab.ui.control.NumericEditField
        R1EditFieldLabel            matlab.ui.control.Label
        R1Text                      matlab.ui.control.NumericEditField
        R2EditFieldLabel            matlab.ui.control.Label
        R2Text                      matlab.ui.control.NumericEditField
        R3EditFieldLabel            matlab.ui.control.Label
        R3Text                      matlab.ui.control.NumericEditField
        R4EditFieldLabel            matlab.ui.control.Label
        R4Text                      matlab.ui.control.NumericEditField
        RTEditFieldLabel            matlab.ui.control.Label
        RTText                      matlab.ui.control.NumericEditField
        CalcularResistenciasButton  matlab.ui.control.Button
        R1AVWbLabel                 matlab.ui.control.Label
        R2AVWbLabel                 matlab.ui.control.Label
        R3AVWbLabel                 matlab.ui.control.Label
        R4AVWbLabel                 matlab.ui.control.Label
        R5AVWbLabel                 matlab.ui.control.Label
        FlujoElectromagneticoLabel  matlab.ui.control.Label
        FlujoText                   matlab.ui.control.NumericEditField
        CalcularFlujoButton         matlab.ui.control.Button
        WbLabel                     matlab.ui.control.Label
        UEditFieldLabel             matlab.ui.control.Label
        UText                       matlab.ui.control.NumericEditField
        CalcularUButton             matlab.ui.control.Button
        HEditFieldLabel             matlab.ui.control.Label
        HText                       matlab.ui.control.NumericEditField
        CalcularHButton             matlab.ui.control.Button
        LensLabel                   matlab.ui.control.Label
        HmLabel                     matlab.ui.control.Label
        BEditFieldLabel             matlab.ui.control.Label
        BText                       matlab.ui.control.NumericEditField
        CalcularBButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        M0=( (4*pi)*10^-7 ) % Constante
    end
    
    methods (Access = private)
        
        function calcR(app)
            app.R1Text.Value = ((app.m3z.Value+(app.m2z.Value/2)+(app.m4z.Value/2))/100)/ ((app.mu.Value) * app.M0 * ((app.m1x.Value * app.mp.Value)/10000));
            app.R2Text.Value = ((app.m4x.Value+(app.m1x.Value/2)+(app.m3x.Value/2))/100)/ ((app.mu.Value) * app.M0 * ((app.m2z.Value * app.mp.Value)/10000));
            app.R3Text.Value = ((app.m3z.Value+(app.m2z.Value/2)+(app.m4z.Value/2))/100)/ ((app.mu.Value) * app.M0 * ((app.m3x.Value * app.mp.Value)/10000));
            
            app.R4Text.Value = ((app.m4x.Value+(app.m1x.Value/2)+(app.m3x.Value/2))/100)/ ((app.mu.Value) * app.M0 * ((app.m4z.Value * app.mp.Value)/10000));
            
            app.RTText.Value = app.R1Text.Value + app.R2Text.Value + app.R3Text.Value + app.R4Text.Value;
        end
        
        function flujo(app)
            app.FlujoText.Value = ((app.vueltas.Value)*(app.amperaje.Value))/(app.RTText.Value);
            
        end
        
        function U(app)
            app.UText.Value = (app.mu.Value)*(app.M0);
        end
        
        function H(app)
            app.HText.Value = ((app.vueltas.Value)*(app.amperaje.Value))/((app.m1x.Value+(app.m4x.Value * 2)+app.m3x.Value+app.m4z.Value+(app.m3z.Value * 2)+ app.m2z.Value)/100);
        end
        
        function B(app)
            app.BText.Value = (app.UText.Value * app.HText.Value);
        end
    end
    

    methods (Access = private)

        % Button pushed function: CalcularResistenciasButton
        function CalcularResistenciasButtonPushed(app, event)
            calcR(app);
        end

        % Value changed function: mu
        function muValueChanged(app, event)
            calcR(app);
        end

        % Value changed function: m1x
        function m1xValueChanged(app, event)
            calcR(app);
        end

        % Value changed function: m4x
        function m4xValueChanged(app, event)
            calcR(app);
        end

        % Value changed function: m3x
        function m3xValueChanged(app, event)
            calcR(app);
        end

        % Value changed function: mp
        function mpValueChanged(app, event)
            calcR(app);
        end

        % Value changed function: m4z
        function m4zValueChanged(app, event)
            calcR(app);
        end

        % Value changed function: m3z
        function m3zValueChanged(app, event)
            calcR(app);
        end

        % Value changed function: m2z
        function m2zValueChanged(app, event)
            calcR(app);
        end

        % Button pushed function: CalcularFlujoButton
        function CalcularFlujoButtonPushed(app, event)
            flujo(app);
        end

        % Button pushed function: CalcularUButton
        function CalcularUButtonPushed(app, event)
            U(app);
        end

        % Button pushed function: CalcularHButton
        function CalcularHButtonPushed(app, event)
            H(app);
        end

        % Button pushed function: CalcularBButton
        function CalcularBButtonPushed(app, event)
            B(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'Transformador.jpg';
            app.Button.Text = '';
            app.voltaje = uieditfield(app.UIFigure, 'numeric');
            app.voltaje.Limits = [0 Inf];
            app.amperaje = uieditfield(app.UIFigure, 'numeric');
            app.amperaje.Limits = [0 Inf];
            app.m1x = uieditfield(app.UIFigure, 'numeric');
            app.m1x.Limits = [0.001 Inf];
            app.m1x.ValueChangedFcn = createCallbackFcn(app, @m1xValueChanged, true);
            app.m1x.Value = 0.001;
            app.m4x = uieditfield(app.UIFigure, 'numeric');
            app.m4x.Limits = [0.001 Inf];
            app.m4x.ValueChangedFcn = createCallbackFcn(app, @m4xValueChanged, true);
            app.m4x.Value = 0.001;
            app.m3x = uieditfield(app.UIFigure, 'numeric');
            app.m3x.Limits = [0.001 Inf];
            app.m3x.ValueChangedFcn = createCallbackFcn(app, @m3xValueChanged, true);
            app.m3x.Value = 0.001;
            app.mp = uieditfield(app.UIFigure, 'numeric');
            app.mp.Limits = [0.001 Inf];
            app.mp.ValueChangedFcn = createCallbackFcn(app, @mpValueChanged, true);
            app.mp.Value = 0.001;
            app.m4z = uieditfield(app.UIFigure, 'numeric');
            app.m4z.Limits = [0.001 Inf];
            app.m4z.ValueChangedFcn = createCallbackFcn(app, @m4zValueChanged, true);
            app.m4z.Value = 0.001;
            app.m3z = uieditfield(app.UIFigure, 'numeric');
            app.m3z.Limits = [0.001 Inf];
            app.m3z.ValueChangedFcn = createCallbackFcn(app, @m3zValueChanged, true);
            app.m3z.Value = 0.001;
            app.m2z = uieditfield(app.UIFigure, 'numeric');
            app.m2z.Limits = [0.001 Inf];
            app.m2z.ValueChangedFcn = createCallbackFcn(app, @m2zValueChanged, true);
            app.m2z.Value = 0.001;
            app.mu = uieditfield(app.UIFigure, 'numeric');
            app.mu.Limits = [0 Inf];
            app.mu.ValueChangedFcn = createCallbackFcn(app, @muValueChanged, true);
            app.vueltas = uieditfield(app.UIFigure, 'numeric');
            app.vueltas.Limits = [1 Inf];
            app.vueltas.Value = 1;
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1Text = uieditfield(app.UIFigure, 'numeric');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2Text = uieditfield(app.UIFigure, 'numeric');
            app.R3EditFieldLabel = uilabel(app.UIFigure);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3Text = uieditfield(app.UIFigure, 'numeric');
            app.R4EditFieldLabel = uilabel(app.UIFigure);
            app.R4EditFieldLabel.Text = 'R4';
            app.R4Text = uieditfield(app.UIFigure, 'numeric');
            app.RTEditFieldLabel = uilabel(app.UIFigure);
            app.RTEditFieldLabel.Text = 'RT';
            app.RTText = uieditfield(app.UIFigure, 'numeric');
            app.CalcularResistenciasButton = uibutton(app.UIFigure, 'push');
            app.CalcularResistenciasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularResistenciasButtonPushed, true);
            app.CalcularResistenciasButton.Text = 'Calcular Resistencias';
            app.R1AVWbLabel = uilabel(app.UIFigure);
            app.R1AVWbLabel.Text = 'AV/Wb';
            app.R2AVWbLabel = uilabel(app.UIFigure);
            app.R2AVWbLabel.Text = 'AV/Wb';
            app.R3AVWbLabel = uilabel(app.UIFigure);
            app.R3AVWbLabel.Text = 'AV/Wb';
            app.R4AVWbLabel = uilabel(app.UIFigure);
            app.R4AVWbLabel.Text = 'AV/Wb';
            app.R5AVWbLabel = uilabel(app.UIFigure);
            app.R5AVWbLabel.Text = 'AV/Wb';
            app.FlujoElectromagneticoLabel = uilabel(app.UIFigure);
            app.FlujoElectromagneticoLabel.Text = {'Flujo'; 'Electromagnetico'};
            app.FlujoText = uieditfield(app.UIFigure, 'numeric');
            app.CalcularFlujoButton = uibutton(app.UIFigure, 'push');
            app.CalcularFlujoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularFlujoButtonPushed, true);
            app.CalcularFlujoButton.Text = 'Calcular Flujo';
            app.WbLabel = uilabel(app.UIFigure);
            app.WbLabel.Text = 'Wb';
            app.UEditFieldLabel = uilabel(app.UIFigure);
            app.UEditFieldLabel.Text = 'U';
            app.UText = uieditfield(app.UIFigure, 'numeric');
            app.CalcularUButton = uibutton(app.UIFigure, 'push');
            app.CalcularUButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularUButtonPushed, true);
            app.CalcularUButton.Text = 'Calcular U';
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HText = uieditfield(app.UIFigure, 'numeric');
            app.CalcularHButton = uibutton(app.UIFigure, 'push');
            app.CalcularHButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularHButtonPushed, true);
            app.CalcularHButton.Text = 'Calcular H';
            app.LensLabel = uilabel(app.UIFigure);
            app.LensLabel.Text = 'Lens';
            app.HmLabel = uilabel(app.UIFigure);
            app.HmLabel.Text = 'H/m';
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BText = uieditfield(app.UIFigure, 'numeric');
            app.CalcularBButton = uibutton(app.UIFigure, 'push');
            app.CalcularBButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularBButtonPushed, true);
            app.CalcularBButton.Text = 'Calcular B';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calculadora_de_Transformadores

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end